classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HomePanel                   matlab.ui.container.Panel
        ContinueButton              matlab.ui.control.Button
        SelectedEEGEMGPathTextArea  matlab.ui.control.TextArea
        SelectedTDTFolderTextArea   matlab.ui.control.TextArea
        ClickiffromTDTCheckBox      matlab.ui.control.CheckBox
        SelectEEGEMGFileButton      matlab.ui.control.Button
        SelectTDTFolderButton       matlab.ui.control.Button
        ProgressPanel               matlab.ui.container.Panel
        SaveFileButton              matlab.ui.control.Button
        ProgressTextArea            matlab.ui.control.TextArea
        TDTPanel                    matlab.ui.container.Panel
        SignalNameLabel             matlab.ui.control.Label
        SignalDNameEditField        matlab.ui.control.EditField
        SignalCNameEditField        matlab.ui.control.EditField
        SignalBNameEditField        matlab.ui.control.EditField
        SignalANameEditField        matlab.ui.control.EditField
        TTLChannelDropDown          matlab.ui.control.DropDown
        TTLChannelDropDown_2Label   matlab.ui.control.Label
        RefChannelDDropDown         matlab.ui.control.DropDown
        RefChannelCDropDown         matlab.ui.control.DropDown
        RefChannelBDropDown         matlab.ui.control.DropDown
        RefChannelADropDown         matlab.ui.control.DropDown
        ReferenceChannelLabel       matlab.ui.control.Label
        ChannelDField               matlab.ui.control.EditField
        ChannelDEditFieldLabel_2    matlab.ui.control.Label
        ChannelCField               matlab.ui.control.EditField
        ChannelCEditFieldLabel_2    matlab.ui.control.Label
        ChannelBField               matlab.ui.control.EditField
        ChannelBEditFieldLabel_2    matlab.ui.control.Label
        ContinueButton2             matlab.ui.control.Button
        ChannelAField               matlab.ui.control.EditField
        ChannelAEditFieldLabel_2    matlab.ui.control.Label
    end

    
    properties (Access = private)
        currentDir = fileparts(mfilename('fullpath')); 
        defaultUigetdir
        TDTDir
        EEGPath
        TDTData
        channelIndex = {'A'; 'B'; 'C'; 'D'}
        isosbesticChannels
        signalChannels
        channelInfo
        fpFrequency
        ttlChannel
        onsetFP
        nChannel
        signalLengthCut
        signalsCorrected
    end
    
    methods (Access = private)
        
        function [] = read_TDT_files(app)
            app.TDTData = TDTbin2mat(app.TDTDir);
            % Define the channels

            channels = fieldnames(app.TDTData.streams);
            app.isosbesticChannels = [];
            app.channelInfo = table( ...
                'Size',[4, 4], ...
                'RowNames', app.channelIndex, ...
                'VariableNames', {'channel', 'signalName', 'refChannel', 'samplingRate'}, ...
                'VariableTypes', {'string', 'string', 'string', 'double'} ...
            );
            
            for i = 1:length(channels)
                channel = channels{i};
                % Extract the numeric digits from the channel string
                tokens = regexp(channel, '^x(\d+)([A-D]$)', 'tokens');
                if ~isempty(tokens)
                    wavelength = str2double(tokens{1}{1});
                    channelInd = tokens{1}{2};
                    if wavelength <= 420 % isosbestic channel
                        app.isosbesticChannels = [app.isosbesticChannels channels(i)];
                        app.channelInfo.refChannel(channelInd) = channel;
                    else
                        samplingRate = app.TDTData.streams.(channel).fs;
                        app.channelInfo.channel(channelInd) = channel;
                        app.channelInfo.samplingRate(channelInd) = samplingRate;
                    end
                end
            end
            app.fpFrequency = app.channelInfo.samplingRate(1);

            % set default refChanne; for B and D
            app.channelInfo.refChannel('B') = 'x405A';
            app.channelInfo.refChannel('D') = 'x405C';
        end
        
        function [] = display_TDT_channels(app)
            % provide the option for not using a ref channel
            app.isosbesticChannels(end+1) = {'none'};
            for k = 1:numel(app.channelIndex)
                L = app.channelIndex{k};
                editFieldName = sprintf('Channel%sField', L);
                dropDownName = sprintf('RefChannel%sDropDown', L);
                app.(editFieldName).Value = app.channelInfo.channel(L);
                app.(dropDownName).Items = app.isosbesticChannels;
                app.(dropDownName).Value = app.channelInfo.refChannel(L);
            end

            % ttlChan
            ttlChannels = fieldnames(app.TDTData.epocs);
            app.TTLChannelDropDown.Items = ttlChannels;
            app.TTLChannelDropDown.Value = ttlChannels{1};
        end
        
        function onsetFP = get_fp_onset(app)
            app.ttlChannel = app.TTLChannelDropDown.Value;
            if ~strcmp(app.ttlChannel, 'none')
                ttlFP = app.TDTData.epocs.(app.ttlChannel).onset;
                ttlGap = diff(ttlFP) > 6;
                if isempty(find(ttlGap == 1, 1))
                    ttlOnset = ttlFP(1);  % when TTL pulse train is only started once
                else 
                    ttlOnset = ttlFP(find(ttlGap==1)+1); % when TTL pulse train is started more than once
                end
                app.fpFrequency = app.channelInfo.samplingRate(1);
                onsetFP = round(ttlOnset(1)*app.fpFrequency); 
            else
                onsetFP = 1;
            end
        end

        function signalsCorrected = process_fp_signal_parallel(app, signals, refSignals, signalsCorrected, signalChannels, onsetFP)
            q = parallel.pool.DataQueue;
            afterEach(q, @updateProgress);
            app.ProgressTextArea.Value{end+1} = 'Parallel computing available. Processing in parallel...';
            parfor i = 1:length(signalChannels)
                channel = signalChannels(i);
                refSignal = refSignals(i, :);
                refSignal = refSignal(onsetFP:end);
                signal = signals(i, :);
                signal = signal(onsetFP:end);
                signalCorrected = correct_fp_signal(signal, refSignal);
                signalsCorrected(i, :) = signalCorrected;
                send(q, channel);
            end
        
            function updateProgress(channel)
                message = sprintf('Processed signal from %s.', channel);
                app.ProgressTextArea.Value{end+1} = message;
                drawnow;
            end
        
        end

        function signalNameAdded = add_signal_names(app)
            signalNameAdded = false;
            for k = 1:numel(app.channelIndex)
                L = app.channelIndex{k};
                editFieldName = sprintf('Signal%sNameEditField', L);
                sigName = app.(editFieldName).Value;
                if ~isempty(sigName)
                    app.channelInfo.signalName(L) = sigName;
                    signalNameAdded = true;
                end
            end
        end
        
        function signalsCorrected = process_fp_signal(app, signals, refSignals, signalsCorrected, signalChannels, onsetFP)
            for i = 1:length(signalChannels)
                channel = signalChannels(i);
                refSignal = refSignals(i, :);
                refSignal = refSignal(onsetFP:end);
                signal = signals(i, :);
                signal = signal(onsetFP:end);
                signalCorrected = correct_fp_signal(signal, refSignal);
                signalsCorrected(i, :) = signalCorrected;
                message = sprintf('Processed signal from %s.', channel);
                app.ProgressTextArea.Value{end+1} = message;
                drawnow;
            end 
        end
        
        function signalsCorrected = process_fp_signals(app)
            app.onsetFP = app.get_fp_onset;
            app.channelInfo = rmmissing(app.channelInfo, 'DataVariables', 'signalName');
            %app.fpFrequency = app.channelInfo.samplingRate(1);
            app.signalChannels = app.channelInfo.channel;
            signalLength = length(app.TDTData.streams.(app.signalChannels(1)).data);
            app.signalLengthCut = signalLength - app.onsetFP + 1;
            app.nChannel = height(app.channelInfo);
            signals = zeros(app.nChannel, signalLength);
            refSignals = zeros(app.nChannel, signalLength);
            signalsCorrected = zeros(app.nChannel, app.signalLengthCut);
            
            for i = 1:app.nChannel
                channel = app.channelInfo.channel(i);
                refChannel = app.channelInfo.refChannel(i);
                signals(i,:) = app.TDTData.streams.(channel).data;
                refSignals(i, :) = app.TDTData.streams.(refChannel).data;
            end

            if length(app.signalChannels) > 1 && canUseParallelPool
                pool = gcp('nocreate');
                if isempty(pool)
                    parpool(2)
                end
                signalsCorrected = process_fp_signal_parallel(app, signals, refSignals, signalsCorrected, app.signalChannels, app.onsetFP);
            else
                signalsCorrected = process_fp_signal(app, signals, refSignals, signalsCorrected, app.signalChannels, app.onsetFP);
            end
            
        end

        function [] = reset_app(app)
            app.HomePanel.Visible = 'on';
            app.ContinueButton.Enable = "off";
            app.SaveFileButton.Enable = 'off';
            app.SaveFileButton.Visible = 'off';
            app.SelectedTDTFolderTextArea.Value = '';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(fullfile(app.currentDir, 'func'), ...
                fullfile(app.currentDir, 'util') ...
            );
            reset_app(app);
        end

        % Button pushed function: SelectTDTFolderButton
        function SelectTDTFolderButtonPushed(app, event)
            f = figure('Position', [-100 -100 0 0]); % create a dummy figure so that uigetfile doesn't minimize our GUI
            selectedDir= uigetdir;
            [parentFolder, ~, ~] = fileparts(string(selectedDir));
            app.defaultUigetdir = parentFolder;
            % Check if a directory was selected (uigetdir returns 0 if the user cancels the dialog)
            delete(f);
            if selectedDir ~= 0
                % Store the selected directory path in the Edit Field
                app.TDTDir = selectedDir;
                app.SelectedTDTFolderTextArea.Value = selectedDir;
                app.ContinueButton.Enable = "on";
            end
        end

        % Button pushed function: SelectEEGEMGFileButton
        function SelectEEGEMGFileButtonPushed(app, event)
            f = figure('Position', [-100 -100 0 0]); % create a dummy figure so that uigetfile doesn't minimize our GUI
            [selectedFile, parentFolder] = uigetfile({'*.exp;*.edf;'});
            app.defaultUigetdir = parentFolder;
            % Check if a directory was selected (uigetdir returns 0 if the user cancels the dialog)
            delete(f);
            if selectedFile ~= 0
                % Store the selected directory path in the Edit Field
                app.EEGPath = fullfile(parentFolder, selectedFile);
                app.SelectedEEGEMGPathTextArea.Value = fullfile(parentFolder, selectedFile);
            end

        end

        % Value changed function: ClickiffromTDTCheckBox
        function ClickiffromTDTCheckBoxValueChanged(app, event)
            checked = app.ClickiffromTDTCheckBox.Value;
            if checked == 1
                app.EEGPath = app.TDTDir;
                app.SelectedEEGEMGPathTextArea.Value = app.EEGPath;
            else
                app.SelectedEEGEMGPathTextArea.Value = '';
            end
            
        end

        % Button pushed function: ContinueButton2
        function ContinueButton2Pushed(app, event)
            signalNameAdded = add_signal_names(app);
            if ~signalNameAdded
                message = strcat("Processed signals saved to ", full_file_path);
                title = 'File Saved';
                icon = 'success';
                uialert(app.UIFigure, message, title, 'Icon', icon);
            end
            
            app.TDTPanel.Visible = 'off';
            app.ProgressPanel.Visible = "on";
            app.ProgressTextArea.Value = {'Processing...'};
            drawnow;
            app.signalsCorrected = process_fp_signals(app);
            app.ProgressTextArea.Value{end+1} = 'Done.';
            app.SaveFileButton.Enable = 'on';
            app.SaveFileButton.Visible = 'on';
            %app.ProgressPanel.Visible = "off";
            %app.ProgressTextArea.Value = {''};
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.HomePanel.Visible = 'off';
            message = {'Reading data... This may take a minute.'};
            app.ProgressTextArea.Value = message;
            app.ProgressPanel.Visible = 'on';
            app.ContinueButton.Enable = "off";
            drawnow; % must do drawnow to force update the app to see the page turn
            read_TDT_files(app)
            display_TDT_channels(app)
            app.ProgressPanel.Visible = 'off';
            app.ProgressTextArea.Value = {''};
            app.TDTPanel.Visible = 'on';
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            f = figure('Position', [-100 -100 0 0]); % create a dummy figure so that uigetfile doesn't minimize our GUI
            %selectedDir = uigetdir(app.currentDir);
            [~, name, ~] = fileparts(string(app.TDTDir));
            defaultSaveName = string(name) + '.mat';
            [filename, path, ~] = uiputfile('*.mat', 'Save File', defaultSaveName);
            % Check if a directory was selected (uigetdir returns 0 if the user cancels the dialog)
            delete(f);
            if path ~= 0
                app.SaveFileButton.Enable = 'off';
                drawnow
                full_file_path = fullfile(path, filename);
                full_file_path = string(full_file_path);
                processedSignals = cell2struct(mat2cell(app.signalsCorrected, ones(1,app.nChannel), app.signalLengthCut), cellstr(app.signalChannels'), 1);
                save(full_file_path, '-struct', 'processedSignals');

                message = strcat("Processed signals saved to ", full_file_path);
                title = 'File Saved';
                icon = 'success';
                uialert(app.UIFigure, message, title, 'Icon', icon);
                reset_app(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TDTPanel = uipanel(app.UIFigure);
            app.TDTPanel.Title = 'TDT';
            app.ChannelAEditFieldLabel_2 = uilabel(app.TDTPanel);
            app.ChannelAEditFieldLabel_2.Text = 'Channel A';
            app.ChannelAField = uieditfield(app.TDTPanel, 'text');
            app.ChannelAField.Editable = 'off';
            app.ContinueButton2 = uibutton(app.TDTPanel, 'push');
            app.ContinueButton2.ButtonPushedFcn = createCallbackFcn(app, @ContinueButton2Pushed, true);
            app.ContinueButton2.Text = 'Continue';
            app.ChannelBEditFieldLabel_2 = uilabel(app.TDTPanel);
            app.ChannelBEditFieldLabel_2.Text = 'Channel B';
            app.ChannelBField = uieditfield(app.TDTPanel, 'text');
            app.ChannelBField.Editable = 'off';
            app.ChannelCEditFieldLabel_2 = uilabel(app.TDTPanel);
            app.ChannelCEditFieldLabel_2.Text = 'Channel C';
            app.ChannelCField = uieditfield(app.TDTPanel, 'text');
            app.ChannelCField.Editable = 'off';
            app.ChannelDEditFieldLabel_2 = uilabel(app.TDTPanel);
            app.ChannelDEditFieldLabel_2.Text = 'Channel D';
            app.ChannelDField = uieditfield(app.TDTPanel, 'text');
            app.ChannelDField.Editable = 'off';
            app.ReferenceChannelLabel = uilabel(app.TDTPanel);
            app.ReferenceChannelLabel.Text = 'Reference Channel';
            app.RefChannelADropDown = uidropdown(app.TDTPanel);
            app.RefChannelADropDown.Items = {'none'};
            app.RefChannelADropDown.Value = 'none';
            app.RefChannelBDropDown = uidropdown(app.TDTPanel);
            app.RefChannelBDropDown.Items = {'none'};
            app.RefChannelBDropDown.Value = 'none';
            app.RefChannelCDropDown = uidropdown(app.TDTPanel);
            app.RefChannelCDropDown.Items = {'none'};
            app.RefChannelCDropDown.Value = 'none';
            app.RefChannelDDropDown = uidropdown(app.TDTPanel);
            app.RefChannelDDropDown.Items = {'none'};
            app.RefChannelDDropDown.Value = 'none';
            app.TTLChannelDropDown_2Label = uilabel(app.TDTPanel);
            app.TTLChannelDropDown_2Label.Text = 'TTL Channel';
            app.TTLChannelDropDown = uidropdown(app.TDTPanel);
            app.TTLChannelDropDown.Items = {'none'};
            app.TTLChannelDropDown.Value = 'none';
            app.SignalANameEditField = uieditfield(app.TDTPanel, 'text');
            app.SignalBNameEditField = uieditfield(app.TDTPanel, 'text');
            app.SignalCNameEditField = uieditfield(app.TDTPanel, 'text');
            app.SignalDNameEditField = uieditfield(app.TDTPanel, 'text');
            app.SignalNameLabel = uilabel(app.TDTPanel);
            app.SignalNameLabel.Text = 'Signal Name';
            app.ProgressPanel = uipanel(app.UIFigure);
            app.ProgressPanel.Title = 'Progress';
            app.ProgressTextArea = uitextarea(app.ProgressPanel);
            app.SaveFileButton = uibutton(app.ProgressPanel, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Enable = 'off';
            app.SaveFileButton.Visible = 'off';
            app.SaveFileButton.Text = 'Save File';
            app.HomePanel = uipanel(app.UIFigure);
            app.HomePanel.Title = 'Panel';
            app.SelectTDTFolderButton = uibutton(app.HomePanel, 'push');
            app.SelectTDTFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTDTFolderButtonPushed, true);
            app.SelectTDTFolderButton.Text = 'Select TDT Folder';
            app.SelectEEGEMGFileButton = uibutton(app.HomePanel, 'push');
            app.SelectEEGEMGFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEEGEMGFileButtonPushed, true);
            app.SelectEEGEMGFileButton.Text = 'Select EEG/EMG File';
            app.ClickiffromTDTCheckBox = uicheckbox(app.HomePanel);
            app.ClickiffromTDTCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClickiffromTDTCheckBoxValueChanged, true);
            app.ClickiffromTDTCheckBox.Text = 'Click if from TDT';
            app.SelectedTDTFolderTextArea = uitextarea(app.HomePanel);
            app.SelectedTDTFolderTextArea.Editable = 'off';
            app.SelectedEEGEMGPathTextArea = uitextarea(app.HomePanel);
            app.SelectedEEGEMGPathTextArea.Editable = 'off';
            app.ContinueButton = uibutton(app.HomePanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end